classdef arduinoApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        STOPButton                   matlab.ui.control.Button
        ROPSlidermmsecLabel          matlab.ui.control.Label
        ROPSlidermmsec               matlab.ui.control.Slider
        DrillModeButtonGroup         matlab.ui.container.ButtonGroup
        AutomaticButton              matlab.ui.control.RadioButton
        ManualButton                 matlab.ui.control.RadioButton
        DrillCommandsLabel           matlab.ui.control.Label
        PositionKnobLabel            matlab.ui.control.Label
        PositionKnob                 matlab.ui.control.DiscreteKnob
        WOBNEditFieldLabel           matlab.ui.control.Label
        WOBNEditField                matlab.ui.control.NumericEditField
        LimitSwitchReachedLampLabel  matlab.ui.control.Label
        LimitSwitchReachedLamp       matlab.ui.control.Lamp
        RPMSliderLabel               matlab.ui.control.Label
        RPMSlider                    matlab.ui.control.Slider
        DrillPosmmEditFieldLabel     matlab.ui.control.Label
        DrillPosmmEditField          matlab.ui.control.NumericEditField
        MirageCommandsLabel          matlab.ui.control.Label
        DirectionSwitchLabel         matlab.ui.control.Label
        DirectionSwitch              matlab.ui.control.Switch
        PositonSpinnerLabel          matlab.ui.control.Label
        PositonSpinner               matlab.ui.control.Spinner
        Image                        matlab.ui.control.Image
        HeaterSwitchLabel            matlab.ui.control.Label
        HeaterSwitch                 matlab.ui.control.Switch
        PumpSwitchLabel              matlab.ui.control.Label
        PumpSwitch                   matlab.ui.control.Switch
        OtherCommandsLabel           matlab.ui.control.Label
        DrillCurrentAEditFieldLabel  matlab.ui.control.Label
        DrillCurrentAEditField       matlab.ui.control.NumericEditField
        MiragePosdegEditFieldLabel   matlab.ui.control.Label
        MiragePosdegEditField        matlab.ui.control.NumericEditField
        DirectionKnobLabel           matlab.ui.control.Label
        DirectionKnob                matlab.ui.control.DiscreteKnob
        TareLoadCellButton           matlab.ui.control.Button
        DrillRPMEditFieldLabel       matlab.ui.control.Label
        DrillRPMEditField            matlab.ui.control.NumericEditField
        ZeroDrillSwitchLabel         matlab.ui.control.Label
        ZeroDrillSwitch              matlab.ui.control.Switch
        FakeLimitSwitchActiveLabel   matlab.ui.control.Label
        FakeLimitSwitchActive        matlab.ui.control.Switch
        DrillSwitchLabel             matlab.ui.control.Label
        DrillSwitch                  matlab.ui.control.Switch
        WOBSetpointSliderLabel       matlab.ui.control.Label
        WOBSetpointSlider            matlab.ui.control.Slider
    end

    
    properties (Access = public)
        App_Closed
        ROP_Speed_Cmd
        ROP_Direction_Cmd
        Drilling_Mode
        Mirage_Position_Cmd
        Mirage_Direction_Cmd
        RPMSpeed_Cmd
        Pump_Cmd
        Heater_Cmd
        Drill_Cmd
        Tare_Cmd
        DrillZero_Cmd
        FakeZero_Cmd
        WOBsetpoint
        
    end
    
    methods (Access = private)
        
        function reset(app)
            app.ROPSlidermmsec.Value = 0;
            app.ROP_Speed_Cmd = 0; % move speed multiplier

            app.DirectionKnob.Value = "Stop"; % stop mode
            app.ROP_Direction_Cmd = 0;

            app.ManualButton.Value = 1;
            app.Drilling_Mode = 1; % manual mode
            
            app.RPMSlider.Value = 0;
            app.RPMSpeed_Cmd = 0;

            app.PumpSwitch.Value = "Off";
            app.HeaterSwitch.Value = "Off";
            app.Pump_Cmd = 0;
            app.Heater_Cmd = 0;
            app.Tare_Cmd = 0;
            app.DrillZero_Cmd = 0;
            app.ZeroDrillSwitch.Value = "Off";
            
            app.WOBSetpointSlider.Value = 0;
            app.WOBsetpoint = 0;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %na
            app.App_Closed = 0;
            
            app.ROPSlidermmsec.Value = 0;
            app.ROP_Speed_Cmd = 0; % move speed multiplier

            app.DirectionKnob.Value = "Stop"; % stop mode
            app.ROP_Direction_Cmd = 0;

            app.PositonSpinner.Value = 0;
            app.Mirage_Direction_Cmd = 1;
            app.PositionKnob.Value = "0";
            app.Mirage_Position_Cmd = 0;
           
            app.ManualButton.Value = 1;
            app.Drilling_Mode = 1; % manual mode
            
            app.RPMSlider.Value = 0;
            app.RPMSpeed_Cmd = 0;
            
            app.Mirage_Direction_Cmd = 1;
            
            app.PumpSwitch.Value = "Off";
            app.HeaterSwitch.Value = "Off";
            app.Pump_Cmd = 0;
            app.Heater_Cmd = 0;
            app.Tare_Cmd = 0;
            app.DrillZero_Cmd = 0;
            app.FakeZero_Cmd = 0;
            app.Drill_Cmd = 0;
            
            app.WOBSetpointSlider.Value = 0;
            app.WOBsetpoint = 0;
        end

        % Value changed function: DirectionKnob
        function DirectionKnobValueChanged(app, event)
            value = app.DirectionKnob.Value; 
            if (value == "Up")
                app.ROP_Direction_Cmd = -1;
            end
            if (value == "Stop")
                app.ROP_Direction_Cmd = 0;
            end
            if (value == "Down")
                app.ROP_Direction_Cmd = 1;
            end
        end

        % Value changed function: ROPSlidermmsec
        function ROPSlidermmsecValueChanged(app, event)
            value = app.ROPSlidermmsec.Value;
            app.ROP_Speed_Cmd = value;
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
                reset(app);
        end

        % Selection changed function: DrillModeButtonGroup
        function DrillModeButtonGroupSelectionChanged(app, event)
            if (app.AutomaticButton.Value) 
                app.Drilling_Mode = 0; %automatic mode
            elseif (app.ManualButton.Value)
                app.Drilling_Mode = 1; %manual mode
            end
        end

        % Value changing function: ROPSlidermmsec
        function ROPSlidermmsecValueChanging(app, event)
            changingValue = event.Value;
            app.ROP_Speed_Cmd = changingValue;
        end

        % Value changed function: PositionKnob
        function PositionKnobValueChanged(app, event)
            value = app.PositionKnob.Value;
            app.Mirage_Position_Cmd = str2double(value); 
            app.PositonSpinner.Value = str2double(value);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            reset(app); 
            delete(app); 
        end

        % Value changed function: RPMSlider
        function RPMSliderValueChanged(app, event)
            value = app.RPMSlider.Value;
            app.RPMSpeed_Cmd = int16(value);
        end

        % Value changed function: DirectionSwitch
        function DirectionSwitchValueChanged(app, event)
            value = app.DirectionSwitch.Value;
            if (value == "CW")
                dirVal = 1; %CW
            else
                dirVal = -1; %CCW
            end
            app.Mirage_Direction_Cmd = dirVal;
        end

        % Value changed function: PositonSpinner
        function PositonSpinnerValueChanged(app, event)
            value = app.PositonSpinner.Value;
            app.Mirage_Position_Cmd = value;
            app.PositionKnob.Value = int2str(idivide(value,int16(30))*30);
        end

        % Value changed function: HeaterSwitch
        function HeaterSwitchValueChanged(app, event)
            value = app.HeaterSwitch.Value;
            if (value == "On")
                app.Heater_Cmd = 1;
            else
                app.Heater_Cmd = 0;
            end
        end

        % Value changed function: PumpSwitch
        function PumpSwitchValueChanged(app, event)
            value = app.PumpSwitch.Value;
            if (value == "On")
                app.Pump_Cmd = 1;
            else
                app.Pump_Cmd = 0;
            end
        end

        % Button pushed function: TareLoadCellButton
        function TareLoadCellButtonPushed(app, event)
            app.Tare_Cmd = 1;
        end

        % Value changing function: RPMSlider
        function RPMSliderValueChanging(app, event)
            changingValue = event.Value;
            app.RPMSpeed_Cmd = int16(changingValue);
        end

        % Value changed function: ZeroDrillSwitch
        function ZeroDrillSwitchValueChanged(app, event)
            value = app.ZeroDrillSwitch.Value;
            if (value == "On")
                app.DrillZero_Cmd = 1;
            else
                app.DrillZero_Cmd = 0;
            end
        end

        % Value changed function: FakeLimitSwitchActive
        function FakeLimitSwitchActiveValueChanged(app, event)
            value = app.FakeLimitSwitchActive.Value;
            if (value == "On")
                app.FakeZero_Cmd = 1;
            else
                app.FakeZero_Cmd = 0;
            end
        end

        % Value changed function: DrillSwitch
        function DrillSwitchValueChanged(app, event)
            value = app.DrillSwitch.Value;
            if (value == "On")
                app.Drill_Cmd = 1;
            else
                app.Drill_Cmd = 0;
            end
        end

        % Value changing function: WOBSetpointSlider
        function WOBSetpointSliderValueChanging(app, event)
            changingValue = event.Value;
            app.WOBsetpoint = int16(changingValue);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.ROPSlidermmsecLabel = uilabel(app.UIFigure);
            app.ROPSlidermmsecLabel.Text = 'ROP Slider (mm/sec)';
            app.ROPSlidermmsec = uislider(app.UIFigure);
            app.ROPSlidermmsec.Limits = [0 40];
            app.ROPSlidermmsec.ValueChangedFcn = createCallbackFcn(app, @ROPSlidermmsecValueChanged, true);
            app.ROPSlidermmsec.ValueChangingFcn = createCallbackFcn(app, @ROPSlidermmsecValueChanging, true);
            app.DrillModeButtonGroup = uibuttongroup(app.UIFigure);
            app.DrillModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DrillModeButtonGroupSelectionChanged, true);
            app.DrillModeButtonGroup.Title = 'Drill Mode';
            app.AutomaticButton = uiradiobutton(app.DrillModeButtonGroup);
            app.AutomaticButton.Text = 'Automatic';
            app.ManualButton = uiradiobutton(app.DrillModeButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.DrillCommandsLabel = uilabel(app.UIFigure);
            app.DrillCommandsLabel.Text = 'Drill Commands';
            app.PositionKnobLabel = uilabel(app.UIFigure);
            app.PositionKnobLabel.Text = 'Position Knob';
            app.PositionKnob = uiknob(app.UIFigure, 'discrete');
            app.PositionKnob.Items = {'0', '30', '60', '90', '120', '150', '180'};
            app.PositionKnob.ValueChangedFcn = createCallbackFcn(app, @PositionKnobValueChanged, true);
            app.PositionKnob.Value = '0';
            app.WOBNEditFieldLabel = uilabel(app.UIFigure);
            app.WOBNEditFieldLabel.Text = 'WOB (N)';
            app.WOBNEditField = uieditfield(app.UIFigure, 'numeric');
            app.LimitSwitchReachedLampLabel = uilabel(app.UIFigure);
            app.LimitSwitchReachedLampLabel.Text = 'Limit Switch Reached';
            app.LimitSwitchReachedLamp = uilamp(app.UIFigure);
            app.RPMSliderLabel = uilabel(app.UIFigure);
            app.RPMSliderLabel.Text = 'RPM Slider';
            app.RPMSlider = uislider(app.UIFigure);
            app.RPMSlider.Limits = [0 1000];
            app.RPMSlider.ValueChangedFcn = createCallbackFcn(app, @RPMSliderValueChanged, true);
            app.RPMSlider.ValueChangingFcn = createCallbackFcn(app, @RPMSliderValueChanging, true);
            app.DrillPosmmEditFieldLabel = uilabel(app.UIFigure);
            app.DrillPosmmEditFieldLabel.Text = 'Drill Pos (mm)';
            app.DrillPosmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.MirageCommandsLabel = uilabel(app.UIFigure);
            app.MirageCommandsLabel.Text = 'Mirage Commands';
            app.DirectionSwitchLabel = uilabel(app.UIFigure);
            app.DirectionSwitchLabel.Text = 'Direction';
            app.DirectionSwitch = uiswitch(app.UIFigure, 'slider');
            app.DirectionSwitch.Items = {'CW', 'CCW'};
            app.DirectionSwitch.ValueChangedFcn = createCallbackFcn(app, @DirectionSwitchValueChanged, true);
            app.DirectionSwitch.Value = 'CW';
            app.PositonSpinnerLabel = uilabel(app.UIFigure);
            app.PositonSpinnerLabel.Text = 'Positon Spinner';
            app.PositonSpinner = uispinner(app.UIFigure);
            app.PositonSpinner.Limits = [0 180];
            app.PositonSpinner.ValueChangedFcn = createCallbackFcn(app, @PositonSpinnerValueChanged, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'AARC5-05.png';
            app.HeaterSwitchLabel = uilabel(app.UIFigure);
            app.HeaterSwitchLabel.Text = 'Heater';
            app.HeaterSwitch = uiswitch(app.UIFigure, 'slider');
            app.HeaterSwitch.ValueChangedFcn = createCallbackFcn(app, @HeaterSwitchValueChanged, true);
            app.PumpSwitchLabel = uilabel(app.UIFigure);
            app.PumpSwitchLabel.Text = 'Pump';
            app.PumpSwitch = uiswitch(app.UIFigure, 'slider');
            app.PumpSwitch.ValueChangedFcn = createCallbackFcn(app, @PumpSwitchValueChanged, true);
            app.OtherCommandsLabel = uilabel(app.UIFigure);
            app.OtherCommandsLabel.Text = 'Other Commands';
            app.DrillCurrentAEditFieldLabel = uilabel(app.UIFigure);
            app.DrillCurrentAEditFieldLabel.Text = 'Drill Current (A)';
            app.DrillCurrentAEditField = uieditfield(app.UIFigure, 'numeric');
            app.MiragePosdegEditFieldLabel = uilabel(app.UIFigure);
            app.MiragePosdegEditFieldLabel.Text = 'Mirage Pos (deg)';
            app.MiragePosdegEditField = uieditfield(app.UIFigure, 'numeric');
            app.DirectionKnobLabel = uilabel(app.UIFigure);
            app.DirectionKnobLabel.Text = 'Direction Knob';
            app.DirectionKnob = uiknob(app.UIFigure, 'discrete');
            app.DirectionKnob.Items = {'Up', 'Stop', 'Down'};
            app.DirectionKnob.ValueChangedFcn = createCallbackFcn(app, @DirectionKnobValueChanged, true);
            app.DirectionKnob.Value = 'Stop';
            app.TareLoadCellButton = uibutton(app.UIFigure, 'push');
            app.TareLoadCellButton.ButtonPushedFcn = createCallbackFcn(app, @TareLoadCellButtonPushed, true);
            app.TareLoadCellButton.Text = 'Tare Load Cell';
            app.DrillRPMEditFieldLabel = uilabel(app.UIFigure);
            app.DrillRPMEditFieldLabel.Text = 'Drill RPM';
            app.DrillRPMEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZeroDrillSwitchLabel = uilabel(app.UIFigure);
            app.ZeroDrillSwitchLabel.Text = 'Zero Drill';
            app.ZeroDrillSwitch = uiswitch(app.UIFigure, 'slider');
            app.ZeroDrillSwitch.ValueChangedFcn = createCallbackFcn(app, @ZeroDrillSwitchValueChanged, true);
            app.FakeLimitSwitchActiveLabel = uilabel(app.UIFigure);
            app.FakeLimitSwitchActiveLabel.Visible = 'off';
            app.FakeLimitSwitchActiveLabel.Text = 'Fake Limit Switch Active';
            app.FakeLimitSwitchActive = uiswitch(app.UIFigure, 'slider');
            app.FakeLimitSwitchActive.ValueChangedFcn = createCallbackFcn(app, @FakeLimitSwitchActiveValueChanged, true);
            app.FakeLimitSwitchActive.Visible = 'off';
            app.DrillSwitchLabel = uilabel(app.UIFigure);
            app.DrillSwitchLabel.Text = 'Drill';
            app.DrillSwitch = uiswitch(app.UIFigure, 'slider');
            app.DrillSwitch.ValueChangedFcn = createCallbackFcn(app, @DrillSwitchValueChanged, true);
            app.WOBSetpointSliderLabel = uilabel(app.UIFigure);
            app.WOBSetpointSliderLabel.Text = 'WOB Setpoint';
            app.WOBSetpointSlider = uislider(app.UIFigure);
            app.WOBSetpointSlider.ValueChangingFcn = createCallbackFcn(app, @WOBSetpointSliderValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = arduinoApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end